classdef Techtalus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Tech_TalusPanel                 matlab.ui.container.Panel
        Std_dev_gyr_zEditField          matlab.ui.control.NumericEditField
        Std_dev_gyr_zEditFieldLabel     matlab.ui.control.Label
        Std_dev_gyr_yEditField          matlab.ui.control.NumericEditField
        Std_dev_gyr_yEditFieldLabel     matlab.ui.control.Label
        Std_dev_gyr_xEditField          matlab.ui.control.NumericEditField
        Std_dev_gyr_xEditFieldLabel     matlab.ui.control.Label
        Std_dev_acc_zEditField          matlab.ui.control.NumericEditField
        Std_dev_acc_zEditFieldLabel     matlab.ui.control.Label
        Std_dev_acc_yEditField          matlab.ui.control.NumericEditField
        Std_dev_acc_yEditFieldLabel     matlab.ui.control.Label
        Std_dev_acc_xEditField          matlab.ui.control.NumericEditField
        Std_dev_acc_xEditFieldLabel     matlab.ui.control.Label
        MeangyroscopezEditField         matlab.ui.control.NumericEditField
        MeangyroscopezEditFieldLabel    matlab.ui.control.Label
        MeangyroscopeyEditField         matlab.ui.control.NumericEditField
        MeangyroscopeyEditFieldLabel    matlab.ui.control.Label
        MeangyroscopexEditField_2       matlab.ui.control.NumericEditField
        MeangyroscopexEditField_2Label  matlab.ui.control.Label
        MeanaccelerometerzEditField     matlab.ui.control.NumericEditField
        MeanaccelerometerzEditFieldLabel  matlab.ui.control.Label
        MeanaccelerometeryEditField     matlab.ui.control.NumericEditField
        MeanaccelerometeryEditFieldLabel  matlab.ui.control.Label
        MeanaccelerometerxEditField_2   matlab.ui.control.NumericEditField
        MeanaccelerometerxEditField_2Label  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a;
        imu;
        x_val;
        x_log;
        y_val;
        y_log;
        z_val;
        z_log;
        x1_val;
        x1_log;
        y1_val;
        y1_log;
        z1_val;
        z1_log;
        accel;
        gyro;
        done;
        start_time;
        stop;
        timeLog;
        timeSecs;
        T;
        threshold;
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.a=arduino();
            app.imu=mpu6050(app.a);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          
            
          app.x_val=animatedline(app.UIAxes,"Color",'r');
          app.y_val=animatedline(app.UIAxes,"Color",'g');
          app.z_val=animatedline(app.UIAxes,"Color",'b');
          axis (app.UIAxes,'tight');
          legend(app.UIAxes,{'Acc_x','Acc_y,','Acc_z'},'Location',"best" );
                  
          app.x1_val=animatedline(app.UIAxes2,"Color",'k');
          app.y1_val=animatedline(app.UIAxes2,"Color",'y');
          app.z1_val=animatedline(app.UIAxes2,"Color",'m');
          axis (app.UIAxes2,'tight');
          legend(app.UIAxes2,{'Ang_x','Ang_y','Ang_z'},'Location',"best");
          
          app.done=0;
          app.stop= false;
          app.start_time= datetime('now');
          
            while ~app.stop
                app.accel=readAcceleration (app.imu);
                t=datetime('now') - app.start_time;
                addpoints(app.x_val,datenum(t),app.accel(:,1));
                addpoints(app.y_val,datenum(t),app.accel(:,2));
                addpoints(app.z_val,datenum(t),app.accel(:,3));
            
                app.gyro=readAngularVelocity (app.imu);
                addpoints(app.x1_val,datenum(t),app.gyro(:,1));
                addpoints(app.y1_val,datenum(t),app.gyro(:,2));
                addpoints(app.z1_val,datenum(t),app.gyro(:,3));
                
            app.stop= app.done;
            drawnow;        
            end      
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.done=1;
            [app.timeLog,app.x_log]=getpoints(app.x_val);
            [app.timeLog,app.y_log]=getpoints(app.y_val);
            [app.timeLog,app.z_log]=getpoints(app.z_val);
            
            [app.timeLog,app.x1_log]=getpoints(app.x1_val);
            [app.timeLog,app.y1_log]=getpoints(app.y1_val);
            [app.timeLog,app.z1_log]=getpoints(app.z1_val);
            
            app.timeSecs= (app.timeLog-app.timeLog(1))*24*3600;
            app.T=table(app.x_log,app.y_log,app.z_log,app.x1_log,app.y1_log,app.z1_log,'VariableNames',{'accelx','accely','accelz','gyrox','gyroy','gyroz';});
            writetable(app.T,'Data.xlsx');
            
            %displaying mean values of accelerometer
            app.MeanaccelerometerxEditField_2.Value= mean(app.x_log);
            app.MeanaccelerometeryEditField.Value= mean(app.y_log);
            app.MeanaccelerometerzEditField.Value= mean(app.z_log);
            
            %displaying mean values of gyroscope
            app.MeangyroscopexEditField_2.Value = mean(app.x1_log);
            app.MeangyroscopeyEditField.Value = mean(app.y1_log);
            app.MeangyroscopezEditField.Value = mean(app.z1_log);
            
            %displaying SD values of accelerometer
            app.Std_dev_acc_xEditField.Value= std(app.x_log);
            app.Std_dev_acc_yEditField.Value= std(app.y_log);
            app.Std_dev_acc_zEditField.Value= std(app.z_log);
            
            %displaying SD values of gyroscope
            app.Std_dev_gyr_xEditField.Value = std(app.x1_log);
            app.Std_dev_gyr_yEditField.Value = std(app.y1_log);
            app.Std_dev_gyr_zEditField.Value = std(app.z1_log);
            
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.MeanaccelerometerxEditField_2.Value=0;
            app.MeanaccelerometeryEditField.Value=0;
            app.MeanaccelerometerzEditField.Value=0;
            app.MeangyroscopexEditField_2.Value=0;
            app.MeangyroscopeyEditField.Value=0;
            app.MeangyroscopezEditField.Value=0;
            app.Std_dev_acc_xEditField.Value= 0;
            app.Std_dev_acc_yEditField.Value= 0;
            app.Std_dev_acc_zEditField.Value= 0;
            app.Std_dev_gyr_xEditField.Value = 0;
            app.Std_dev_gyr_yEditField.Value = 0;
            app.Std_dev_gyr_zEditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tech_TalusPanel = uipanel(app.UIFigure);
            app.Tech_TalusPanel.Title = 'Tech_Talus';
            app.UIAxes = uiaxes(app.Tech_TalusPanel);
            title(app.UIAxes, 'Accelerometer data')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Acceleration (m/s^2)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Tech_TalusPanel);
            title(app.UIAxes2, 'Gyroscope data')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'AngularVelocity (rad/s^2)')
            zlabel(app.UIAxes2, 'Z')
            app.StartButton = uibutton(app.Tech_TalusPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Tech_TalusPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.Tech_TalusPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MeanaccelerometerxEditField_2Label = uilabel(app.Tech_TalusPanel);
            app.MeanaccelerometerxEditField_2Label.Text = 'Mean (accelerometer x)';
            app.MeanaccelerometerxEditField_2 = uieditfield(app.Tech_TalusPanel, 'numeric');
            app.MeanaccelerometeryEditFieldLabel = uilabel(app.Tech_TalusPanel);
            app.MeanaccelerometeryEditFieldLabel.Text = 'Mean (accelerometer y)';
            app.MeanaccelerometeryEditField = uieditfield(app.Tech_TalusPanel, 'numeric');
            app.MeanaccelerometerzEditFieldLabel = uilabel(app.Tech_TalusPanel);
            app.MeanaccelerometerzEditFieldLabel.Text = 'Mean (accelerometer z)';
            app.MeanaccelerometerzEditField = uieditfield(app.Tech_TalusPanel, 'numeric');
            app.MeangyroscopexEditField_2Label = uilabel(app.Tech_TalusPanel);
            app.MeangyroscopexEditField_2Label.Text = 'Mean (gyroscope x)';
            app.MeangyroscopexEditField_2 = uieditfield(app.Tech_TalusPanel, 'numeric');
            app.MeangyroscopeyEditFieldLabel = uilabel(app.Tech_TalusPanel);
            app.MeangyroscopeyEditFieldLabel.Text = 'Mean (gyroscope y)';
            app.MeangyroscopeyEditField = uieditfield(app.Tech_TalusPanel, 'numeric');
            app.MeangyroscopezEditFieldLabel = uilabel(app.Tech_TalusPanel);
            app.MeangyroscopezEditFieldLabel.Text = 'Mean (gyroscope z)';
            app.MeangyroscopezEditField = uieditfield(app.Tech_TalusPanel, 'numeric');
            app.Std_dev_acc_xEditFieldLabel = uilabel(app.Tech_TalusPanel);
            app.Std_dev_acc_xEditFieldLabel.Text = 'Std_dev_acc_x';
            app.Std_dev_acc_xEditField = uieditfield(app.Tech_TalusPanel, 'numeric');
            app.Std_dev_acc_yEditFieldLabel = uilabel(app.Tech_TalusPanel);
            app.Std_dev_acc_yEditFieldLabel.Text = 'Std_dev_acc_y';
            app.Std_dev_acc_yEditField = uieditfield(app.Tech_TalusPanel, 'numeric');
            app.Std_dev_acc_zEditFieldLabel = uilabel(app.Tech_TalusPanel);
            app.Std_dev_acc_zEditFieldLabel.Text = 'Std_dev_acc_z';
            app.Std_dev_acc_zEditField = uieditfield(app.Tech_TalusPanel, 'numeric');
            app.Std_dev_gyr_xEditFieldLabel = uilabel(app.Tech_TalusPanel);
            app.Std_dev_gyr_xEditFieldLabel.Text = 'Std_dev_gyr_x';
            app.Std_dev_gyr_xEditField = uieditfield(app.Tech_TalusPanel, 'numeric');
            app.Std_dev_gyr_yEditFieldLabel = uilabel(app.Tech_TalusPanel);
            app.Std_dev_gyr_yEditFieldLabel.Text = 'Std_dev_gyr_y';
            app.Std_dev_gyr_yEditField = uieditfield(app.Tech_TalusPanel, 'numeric');
            app.Std_dev_gyr_zEditFieldLabel = uilabel(app.Tech_TalusPanel);
            app.Std_dev_gyr_zEditFieldLabel.Text = 'Std_dev_gyr_z';
            app.Std_dev_gyr_zEditField = uieditfield(app.Tech_TalusPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Techtalus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end